classdef appLinearInterpolation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayoutLeft                  matlab.ui.container.GridLayout
        robotSimUIAxes                  matlab.ui.control.UIAxes
        angleUIAxes                     matlab.ui.control.UIAxes
        velUIAxes                       matlab.ui.control.UIAxes
        accUIAxes                       matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        SystemStateLamp                 matlab.ui.control.Lamp
        SystemwirdausgefuehrtLampLabel  matlab.ui.control.Label
        AnhaltenButton                  matlab.ui.control.Button
        GifErzeugenCheckBox             matlab.ui.control.CheckBox
        ZielZeitSpinner                 matlab.ui.control.Spinner
        ZielZeitSpinnerLabel            matlab.ui.control.Label
        BeschlVerlaufCheckBox           matlab.ui.control.CheckBox
        GeschwVerlaufCheckBox           matlab.ui.control.CheckBox
        WinkelVerlaufCheckBox           matlab.ui.control.CheckBox
        BewegungssimulationCheckBox     matlab.ui.control.CheckBox
        ResetButton                     matlab.ui.control.Button
        TargetTimeCheckBox              matlab.ui.control.CheckBox
        BeschlSpinner                   matlab.ui.control.Spinner
        BeschlSpinnerLabel              matlab.ui.control.Label
        GeschwSpinner                   matlab.ui.control.Spinner
        GeschwSpinnerLabel              matlab.ui.control.Label
        ParamLamp                       matlab.ui.control.Lamp
        rConfigCheckBox                 matlab.ui.control.CheckBox
        StartButton                     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        pause_flag = 0;             % Zum Anhalten und weiterfuehren der Darstellungen
        canle_old_runs_flag = 0;    % Flag um angehaltene Interrupts zu beenden
    end

    methods (Access = private)
        
        function resetAxes(app)
            % Alle Axen zurücksetzten
            cla(app.robotSimUIAxes);
            cla(app.angleUIAxes);
            cla(app.velUIAxes);
            cla(app.accUIAxes);

            % Loeschen der Legenden
            legend(app.robotSimUIAxes, 'off');
            legend(app.angleUIAxes, 'off');
            legend(app.velUIAxes, 'off');
            legend(app.accUIAxes, 'off');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Blockieren der checkboxen um ein wechsel waehrend des Durchlaufes zu verhindern
            app.BewegungssimulationCheckBox.Enable = 'off';
            app.WinkelVerlaufCheckBox.Enable = 'off';
            app.GeschwVerlaufCheckBox.Enable = 'off';
            app.BeschlVerlaufCheckBox.Enable = 'off';

            resetAxes(app);

            designAxes(app);

            % Gesetzte Geschwindigkeit und Beschleunigung auslesen
            vm = [app.GeschwSpinner.Value app.GeschwSpinner.Value]';
            am = [app.BeschlSpinner.Value app.BeschlSpinner.Value]';

            % Zielzeitfahrt bestimmen
            if(app.TargetTimeCheckBox.Value)
                t_target = app.ZielZeitSpinner.Value;
            else
                t_target = 0;
            end

            % Signallampe wird gesetzt
            app.SystemStateLamp.Color = 'green';
            drawnow()

            % Berechnung und Darstellung der Bewegungsablaeuf            
            run_project(app, app.robotSimUIAxes, app.angleUIAxes, app.velUIAxes, app.accUIAxes, ...
                        app.rConfigCheckBox.Value, vm, am, t_target, app.GifErzeugenCheckBox);

            % Signallampe wird zurueckgesetzt
            app.SystemStateLamp.Color = [0.90,0.90,0.90];

            % Blockierung aufheben
            app.BewegungssimulationCheckBox.Enable = 'on';
            app.WinkelVerlaufCheckBox.Enable = 'on';
            app.GeschwVerlaufCheckBox.Enable = 'on';
            app.BeschlVerlaufCheckBox.Enable = 'on';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Abbrechen der Aktuellen Simulation
            app.pause_flag = 1;
            app.canle_old_runs_flag = 1;

            % Zuruecksetzten der Signallampe
            app.SystemStateLamp.Color = [0.90,0.90,0.90];
            
            resetAxes(app);

            % Anhalte Button zuruecksetzten
            app.pause_flag = 0;
            app.AnhaltenButton.Text = 'Anhalten';
        end

        % Button pushed function: AnhaltenButton
        function AnhaltenButtonPushed(app, event)
            
            if app.pause_flag == 0
                app.pause_flag = 1;
                app.AnhaltenButton.Text = 'Abspielen';
            else
                app.pause_flag = 0;
                app.AnhaltenButton.Text = 'Anhalten';
            end
        end

        % Value changed function: TargetTimeCheckBox
        function TargetTimeCheckBoxValueChanged(app, event)
            if app.TargetTimeCheckBox.Value
                app.ZielZeitSpinner.Enable = 'on';
            else
                app.ZielZeitSpinner.Enable = 'off';
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {661, 661};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {834, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayoutLeft = uigridlayout(app.LeftPanel);
            app.accUIAxes = uiaxes(app.GridLayoutLeft);
            title(app.accUIAxes, 'Beschleunigungsverlauf')
            xlabel(app.accUIAxes, 'Zeit [t]')
            ylabel(app.accUIAxes, 'Beschleunigung [m/s^2]')
            zlabel(app.accUIAxes, 'Z')
            app.velUIAxes = uiaxes(app.GridLayoutLeft);
            title(app.velUIAxes, 'Geschwindigkeitsverlauf')
            xlabel(app.velUIAxes, 'Zeit [t]')
            ylabel(app.velUIAxes, 'Geschwindigkeit [m/s]')
            zlabel(app.velUIAxes, 'Z')
            app.angleUIAxes = uiaxes(app.GridLayoutLeft);
            title(app.angleUIAxes, 'Winkelverlauf J1 / J2')
            xlabel(app.angleUIAxes, 'Zeit [t]')
            ylabel(app.angleUIAxes, 'Winkel [°]')
            zlabel(app.angleUIAxes, 'Z')
            app.robotSimUIAxes = uiaxes(app.GridLayoutLeft);
            title(app.robotSimUIAxes, 'Bewegungsanimation')
            xlabel(app.robotSimUIAxes, 'Strecke [m]')
            ylabel(app.robotSimUIAxes, 'Strecke [m]')
            zlabel(app.robotSimUIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.RightPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.rConfigCheckBox = uicheckbox(app.RightPanel);
            app.rConfigCheckBox.Text = 'Konfig Umgekehrt';
            app.ParamLamp = uilamp(app.RightPanel);
            app.GeschwSpinnerLabel = uilabel(app.RightPanel);
            app.GeschwSpinnerLabel.Text = 'Geschw.';
            app.GeschwSpinner = uispinner(app.RightPanel);
            app.GeschwSpinner.Step = 0.1;
            app.GeschwSpinner.Limits = [0 Inf];
            app.GeschwSpinner.Value = 3;
            app.BeschlSpinnerLabel = uilabel(app.RightPanel);
            app.BeschlSpinnerLabel.Text = 'Beschl.';
            app.BeschlSpinner = uispinner(app.RightPanel);
            app.BeschlSpinner.Step = 0.1;
            app.BeschlSpinner.Limits = [0 Inf];
            app.BeschlSpinner.Value = 0.2;
            app.TargetTimeCheckBox = uicheckbox(app.RightPanel);
            app.TargetTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TargetTimeCheckBoxValueChanged, true);
            app.TargetTimeCheckBox.Text = 'Zeitfahrt';
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BewegungssimulationCheckBox = uicheckbox(app.RightPanel);
            app.BewegungssimulationCheckBox.Text = 'Bewegungssimulation';
            app.BewegungssimulationCheckBox.Value = true;
            app.WinkelVerlaufCheckBox = uicheckbox(app.RightPanel);
            app.WinkelVerlaufCheckBox.Text = 'Winkel Verlauf';
            app.WinkelVerlaufCheckBox.Value = true;
            app.GeschwVerlaufCheckBox = uicheckbox(app.RightPanel);
            app.GeschwVerlaufCheckBox.Text = 'Geschw. Verlauf';
            app.GeschwVerlaufCheckBox.Value = true;
            app.BeschlVerlaufCheckBox = uicheckbox(app.RightPanel);
            app.BeschlVerlaufCheckBox.Text = 'Beschl. Verlauf';
            app.BeschlVerlaufCheckBox.Value = true;
            app.ZielZeitSpinnerLabel = uilabel(app.RightPanel);
            app.ZielZeitSpinnerLabel.Text = 'Ziel Zeit';
            app.ZielZeitSpinner = uispinner(app.RightPanel);
            app.ZielZeitSpinner.Limits = [0 Inf];
            app.ZielZeitSpinner.Enable = 'off';
            app.GifErzeugenCheckBox = uicheckbox(app.RightPanel);
            app.GifErzeugenCheckBox.Text = 'Gif Erzeugen';
            app.AnhaltenButton = uibutton(app.RightPanel, 'push');
            app.AnhaltenButton.ButtonPushedFcn = createCallbackFcn(app, @AnhaltenButtonPushed, true);
            app.AnhaltenButton.Text = 'Anhalten';
            app.SystemwirdausgefuehrtLampLabel = uilabel(app.RightPanel);
            app.SystemwirdausgefuehrtLampLabel.Text = 'System wird ausgefuehrt';
            app.SystemStateLamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appLinearInterpolation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end