classdef appTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        robotDrive   matlab.ui.control.UIAxes
        RightPanel   matlab.ui.container.Panel
        ResetButton  matlab.ui.control.Button
        PlotButton   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % run test.m
            % [x, y] = test(0.01);
            clc;

            p_punkte = [-40 40;
                        -40 65;
                         40 65;
                         40 15;
                        -40 15;
                        -40 40]';
            
            axis(app.robotDrive, [-100 100 -30 70]);
            plot(app.robotDrive, p_punkte(1, :), p_punkte(2, :), 'k-'); % Rechteck zeichnen
            hold(app.robotDrive, 'on');
            
            x = -40:0.5:40;
            y = ones(1, length(x)) * 65;
            
            % plot(app.robotDrive,x,y);

            h = animatedline(app.robotDrive, 'Marker', 'o', 'Color', 'r');
            
            for k = 1:length(x)
                clearpoints(h);
                addpoints(h, x(k), y(k));
                drawnow();
            end
            
            hold(app.robotDrive, 'off');

            % working ----------------------------------------------
            % h = animatedline(app.robotDrive, 'Marker', 'o', 'Color', 'r');
            % xlim(app.robotDrive, [0 4*pi]);
            % ylim(app.robotDrive, [-1 1]);
            % 
            % x = linspace(0,4*pi,1000);
            % y = sin(x);
            % for k = 1:length(x)
            %     clearpoints(h);
            %     addpoints(h, x(k), y(k));
            %     drawnow();
            % end
            % working ----------------------------------------------


            % app.robotDrive = uiaxes();
            % h = plot(app.robotDrive, nan, nan);
            % hold(app.robotDrive, 'on')
            % grid(app.robotDrive, 'on')
            % xlim(app.robotDrive, [0 2*pi])
            % ylim(app.robotDrive, [-1.5 1.5])
            % 
            % for i = 1:numel(x)
            %     set(h,'XData',x(1:i),'YData',y(1,i))
            %     drawnow();
            %     pause(0.015);
            % end
            % hold(app.robotDrive, 'off')
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.robotDrive)
            grid(app.robotDrive, 'off')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {606, 606};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {727, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.robotDrive = uiaxes(app.LeftPanel);
            title(app.robotDrive, 'Title')
            xlabel(app.robotDrive, 'X')
            ylabel(app.robotDrive, 'Y')
            zlabel(app.robotDrive, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end